classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageTransformationAppTab     matlab.ui.container.Tab
        FiletargetLabel               matlab.ui.control.Label
        FileInputLabel                matlab.ui.control.Label
        TransformationfunctionLabel   matlab.ui.control.Label
        AdditionaSpinner              matlab.ui.control.Spinner
        AdditionaSpinnerLabel         matlab.ui.control.Label
        SelectImageTargetButton       matlab.ui.control.Button
        SelectInputImageLabel_2       matlab.ui.control.Label
        ExponentgammaSpinner          matlab.ui.control.Spinner
        ExponentgammaSpinnerLabel     matlab.ui.control.Label
        ScalercSpinner                matlab.ui.control.Spinner
        ScalercSpinnerLabel           matlab.ui.control.Label
        SelectTransformationDropDown  matlab.ui.control.DropDown
        SelectTransformationDropDownLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        HistogramLabel                matlab.ui.control.Label
        ImageOutputLabel              matlab.ui.control.Label
        ImageInputLabel               matlab.ui.control.Label
        ImageTargetLabel              matlab.ui.control.Label
        RedHistogramTarget            matlab.ui.control.UIAxes
        ImageOutput                   matlab.ui.control.UIAxes
        BlueHistogramInput            matlab.ui.control.UIAxes
        GreenHistogramInput           matlab.ui.control.UIAxes
        RedHistogramInput             matlab.ui.control.UIAxes
        ImageInput                    matlab.ui.control.UIAxes
        BlueHistogramTarget           matlab.ui.control.UIAxes
        GreenHistogramTarget          matlab.ui.control.UIAxes
        ImageTarget                   matlab.ui.control.UIAxes
        BlueHistogramOutput           matlab.ui.control.UIAxes
        GreenHistogramOutput          matlab.ui.control.UIAxes
        RedHistogramOutput            matlab.ui.control.UIAxes
        SelectInputImageLabel         matlab.ui.control.Label
        SelectImageInputButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImageInputSelected 
        ImageInputFileName
        ImageTargetSelected
        ImageTargetFileName
        AlgorithmSelected
        aValue
        cValue
        gammaValue
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageInput.Visible = 'off';
            app.ImageInputLabel.Visible = 'off';
            app.RedHistogramInput.Visible = 'off';
            app.GreenHistogramInput.Visible = 'off';
            app.BlueHistogramInput.Visible = 'off';
    
            app.ImageOutput.Visible = 'off';
            app.ImageOutputLabel.Visible = 'off';
            app.RedHistogramOutput.Visible = 'off';
            app.GreenHistogramOutput.Visible = 'off';
            app.BlueHistogramOutput.Visible = 'off';
    
            app.ImageTarget.Visible = 'off';
            app.ImageTargetLabel.Visible = 'off';
            app.RedHistogramTarget.Visible = 'off';
            app.GreenHistogramTarget.Visible = 'off';
            app.BlueHistogramTarget.Visible = 'off';

            app.AdditionaSpinner.Visible = 'off';
            app.ExponentgammaSpinner.Visible = 'off';
            app.ScalercSpinner.Visible = 'off';
            app.ExponentgammaSpinnerLabel.Visible = 'off';
            app.ScalercSpinnerLabel.Visible = 'off';
            app.AdditionaSpinnerLabel.Visible = 'off';
            app.SelectImageTargetButton.Visible = 'off';
            app.SelectInputImageLabel_2.Visible = 'off';

            app.aValue = 0;
            app.cValue = 1;
            app.gammaValue = 1;
          
        end

        % Button pushed function: SelectImageInputButton
        function SelectImageInputButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                cla(app.RedHistogramOutput);
                cla(app.GreenHistogramOutput);
                cla(app.BlueHistogramOutput);
                cla(app.RedHistogramTarget);
                cla(app.GreenHistogramTarget);
                cla(app.BlueHistogramTarget);
                app.ImageTargetLabel.Visible = 'off';
                app.ImageOutputLabel.Visible = 'off';
                cla(app.ImageOutput);
                cla(app.ImageTarget);
                app.ImageInputFileName = fullfile(path, file);

                app.FileInputLabel.Text = sprintf("File Input: %s", file);
                app.FiletargetLabel.Text = "File Target: -";
                
                img = imread(app.ImageInputFileName);
                imshow(img,'Parent',app.ImageInput);
                
                hist_app(app, img, app.RedHistogramInput, app.GreenHistogramInput, app.BlueHistogramInput);
                app.ImageInputLabel.Visible = 'on';

                selected_algorithm = string(app.AlgorithmSelected);

                switch selected_algorithm
                    case 'Histogram'
                    case 'Brightening'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = brightening(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Negative'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = negativeandinverse(img);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Log Transformation'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = logtransformation(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Exponential Transformation'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = exponent(img,app.cValue,app.gammaValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Contrast Stretching'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = stretching(img);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Histogram Equalization'
                        app.ImageOutputLabel.Visible ='on';
                        outputImg = histogram_equalization(img);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Histogram Matching'
                        app.SelectImageTargetButton.Visible = 'on';
                        app.SelectInputImageLabel_2.Visible = 'on';
                end
            end
        end

        % Value changed function: SelectTransformationDropDown
        function SelectTransformationDropDownValueChanged(app, event)
            value = app.SelectTransformationDropDown.Value;
            app.AlgorithmSelected = value;

            cla(app.RedHistogramInput);
            cla(app.GreenHistogramInput);
            cla(app.BlueHistogramInput);

            cla(app.RedHistogramOutput);
            cla(app.GreenHistogramOutput);
            cla(app.BlueHistogramOutput);

            cla(app.RedHistogramTarget);
            cla(app.GreenHistogramTarget);
            cla(app.BlueHistogramTarget);

            cla(app.ImageInput);
            cla(app.ImageOutput);
            cla(app.ImageTarget);

            app.ImageInput.Visible = 'off';
            app.ImageInputLabel.Visible = 'off';
            app.RedHistogramInput.Visible = 'off';
            app.GreenHistogramInput.Visible = 'off';
            app.BlueHistogramInput.Visible = 'off';
    
            app.ImageOutput.Visible = 'off';
            app.ImageOutputLabel.Visible = 'off';
            app.RedHistogramOutput.Visible = 'off';
            app.GreenHistogramOutput.Visible = 'off';
            app.BlueHistogramOutput.Visible = 'off';
    
            app.ImageTarget.Visible = 'off';
            app.ImageTargetLabel.Visible = 'off';
            app.RedHistogramTarget.Visible = 'off';
            app.GreenHistogramTarget.Visible = 'off';
            app.BlueHistogramTarget.Visible = 'off';

            app.AdditionaSpinner.Visible = 'off';
            app.ExponentgammaSpinner.Visible = 'off';
            app.ScalercSpinner.Visible = 'off';
            app.ExponentgammaSpinnerLabel.Visible = 'off';
            app.ScalercSpinnerLabel.Visible = 'off';
            app.AdditionaSpinnerLabel.Visible = 'off';
            app.SelectImageTargetButton.Visible = 'off';
            app.SelectInputImageLabel_2.Visible = 'off';

            app.FileInputLabel.Text = 'File input: -';
            app.FiletargetLabel.Text = 'File target: -';

            switch value
                case 'Brightening'
                    app.HistogramLabel.Text = 'Brightening';
                    app.TransformationfunctionLabel.Text = 'Transformation function: s = c*r + a';
                    app.AdditionaSpinner.Visible = 'on';
                    app.AdditionaSpinnerLabel.Visible = 'on';
                    app.ScalercSpinner.Visible = 'on';
                    app.ScalercSpinnerLabel.Visible = 'on';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Negative'
                    app.HistogramLabel.Text = 'Negative';
                    app.TransformationfunctionLabel.Text = 'Transformation function: s = 255 - r';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'off';
                    app.ScalercSpinnerLabel.Visible = 'off';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Histogram'
                    app.HistogramLabel.Text = 'Histogram';
                    app.TransformationfunctionLabel.Text = 'Transformation function: -';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'off';
                    app.ScalercSpinnerLabel.Visible = 'off';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Log Transformation'
                    app.HistogramLabel.Text = 'Log Transformation';
                    app.TransformationfunctionLabel.Text = 'Transformation function: s = c*log(r+a+1)';
                    app.AdditionaSpinner.Visible = 'on';
                    app.AdditionaSpinnerLabel.Visible = 'on';
                    app.ScalercSpinner.Visible = 'on';
                    app.ScalercSpinnerLabel.Visible = 'on';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Exponential Transformation'
                    app.HistogramLabel.Text = 'Exponential Transformation';
                    app.TransformationfunctionLabel.Text = 'Transformation function: s = c*r^gamma';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'on';
                    app.ScalercSpinnerLabel.Visible = 'on';
                    app.ExponentgammaSpinner.Visible = 'on';
                    app.ExponentgammaSpinnerLabel.Visible = 'on';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Contrast Stretching'
                    app.HistogramLabel.Text = 'Contrast Stretching';
                    app.TransformationfunctionLabel.Text = 'Transformation function: s = (s - rmin)*(255/(rmax-rmin)';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'off';
                    app.ScalercSpinnerLabel.Visible = 'off';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Histogram Equalization'
                    app.HistogramLabel.Text = 'Histogram Equalization';
                    app.TransformationfunctionLabel.Text = 'Transformation function: -';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'off';
                    app.ScalercSpinnerLabel.Visible = 'off';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                case 'Histogram Matching'
                    app.HistogramLabel.Text = 'Histogram Matching';
                    app.TransformationfunctionLabel.Text = 'Transformation function: -';
                    app.AdditionaSpinner.Visible = 'off';
                    app.AdditionaSpinnerLabel.Visible = 'off';
                    app.ScalercSpinner.Visible = 'off';
                    app.ScalercSpinnerLabel.Visible = 'off';
                    app.ExponentgammaSpinner.Visible = 'off';
                    app.ExponentgammaSpinnerLabel.Visible = 'off';
                    app.SelectImageTargetButton.Visible = 'off';
                    app.SelectInputImageLabel_2.Visible = 'off';
                     
            end

        end

        % Value changed function: AdditionaSpinner
        function AdditionaSpinnerValueChanged(app, event)
            value = app.AdditionaSpinner.Value;
            app.aValue = value;
        end

        % Value changed function: ScalercSpinner
        function ScalercSpinnerValueChanged(app, event)
            value = app.ScalercSpinner.Value;
            app.cValue = value;
        end

        % Value changed function: ExponentgammaSpinner
        function ExponentgammaSpinnerValueChanged(app, event)
            value = app.ExponentgammaSpinner.Value;
            app.gammaValue = value;
        end

        % Button pushed function: SelectImageTargetButton
        function SelectImageTargetButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                app.ImageTargetFileName = fullfile(path, file);
                app.FiletargetLabel.Text = sprintf('File target: %s', file);
                
                imginput = imread(app.ImageInputFileName);
                img = imread(app.ImageTargetFileName);
                imshow(img,'Parent',app.ImageTarget);
                
                hist_app(app, img, app.RedHistogramTarget, app.GreenHistogramTarget, app.BlueHistogramTarget);
                app.ImageTargetLabel.Visible = 'on';

                app.ImageOutputLabel.Visible ='on';
                outputImg = histogram_matching(imginput, img);
                imshow(outputImg, 'Parent', app.ImageOutput);
                hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
            end
        end

        % Value changing function: AdditionaSpinner
        function AdditionaSpinnerValueChanging(app, event)
            changingValue = event.Value;
            disp("value changed")
            app.aValue = changingValue;
            
            if app.ImageInputFileName
                selected_algorithm = string(app.AlgorithmSelected);
                img = imread(app.ImageInputFileName);
    
                switch selected_algorithm
                    case 'Brightening'
                        outputImg = brightening(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Log Transformation'
                        outputImg = logtransformation(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                end
            end

        end

        % Value changing function: ScalercSpinner
        function ScalercSpinnerValueChanging(app, event)
            changingValue = event.Value;
            disp("value changed")
            app.cValue = changingValue;
            
            if app.ImageInputFileName
                selected_algorithm = string(app.AlgorithmSelected);
                img = imread(app.ImageInputFileName);
    
                switch selected_algorithm
                    case 'Brightening'
                        outputImg = brightening(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Log Transformation'
                        outputImg = logtransformation(img,app.cValue,app.aValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                    case 'Exponential Transformation'
                        outputImg = exponent(img,app.cValue,app.gammaValue);
                        imshow(outputImg, 'Parent', app.ImageOutput);
                        hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
                end
            end
        end

        % Value changing function: ExponentgammaSpinner
        function ExponentgammaSpinnerValueChanging(app, event)
            changingValue = event.Value;
            disp("value changed")
            app.gammaValue = changingValue;
            
            if app.ImageInputFileName
                img = imread(app.ImageInputFileName);
                outputImg = exponent(img,app.cValue,app.gammaValue);
                imshow(outputImg, 'Parent', app.ImageOutput);
                hist_app(app, outputImg,app.RedHistogramOutput,app.GreenHistogramOutput,app.BlueHistogramOutput);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTransformationAppTab = uitab(app.TabGroup);
            app.ImageTransformationAppTab.Title = 'Image Transformation App';
            app.SelectImageInputButton = uibutton(app.ImageTransformationAppTab, 'push');
            app.SelectImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageInputButtonPushed, true);
            app.SelectImageInputButton.Text = 'Select Image';
            app.SelectInputImageLabel = uilabel(app.ImageTransformationAppTab);
            app.SelectInputImageLabel.Text = 'Select Input Image';
            app.Panel = uipanel(app.ImageTransformationAppTab);
            app.RedHistogramOutput = uiaxes(app.Panel);
            app.GreenHistogramOutput = uiaxes(app.Panel);
            app.BlueHistogramOutput = uiaxes(app.Panel);
            app.ImageTarget = uiaxes(app.Panel);
            app.ImageTarget.XTick = [];
            app.ImageTarget.YTick = [];
            app.GreenHistogramTarget = uiaxes(app.Panel);
            app.BlueHistogramTarget = uiaxes(app.Panel);
            app.ImageInput = uiaxes(app.Panel);
            app.ImageInput.XTick = [];
            app.ImageInput.YTick = [];
            app.RedHistogramInput = uiaxes(app.Panel);
            xlabel(app.RedHistogramInput, 'Pixel')
            ylabel(app.RedHistogramInput, 'Frequency')
            app.RedHistogramInput.XTick = [0 127.5 255];
            app.RedHistogramInput.XTickLabel = {'0'; '127.5'; '255'};
            app.RedHistogramInput.YTickLabel = {'0'; '512'; '1024'};
            app.GreenHistogramInput = uiaxes(app.Panel);
            xlabel(app.GreenHistogramInput, 'Pixel')
            ylabel(app.GreenHistogramInput, 'Frequency')
            app.BlueHistogramInput = uiaxes(app.Panel);
            app.ImageOutput = uiaxes(app.Panel);
            app.ImageOutput.XTick = [];
            app.ImageOutput.YTick = [];
            app.RedHistogramTarget = uiaxes(app.Panel);
            app.ImageTargetLabel = uilabel(app.Panel);
            app.ImageTargetLabel.Text = 'Image Target';
            app.ImageInputLabel = uilabel(app.Panel);
            app.ImageInputLabel.Text = 'Image Input';
            app.ImageOutputLabel = uilabel(app.Panel);
            app.ImageOutputLabel.Text = 'Image Output';
            app.HistogramLabel = uilabel(app.Panel);
            app.HistogramLabel.Text = 'Histogram';
            app.SelectTransformationDropDownLabel = uilabel(app.ImageTransformationAppTab);
            app.SelectTransformationDropDownLabel.Text = 'Select Transformation';
            app.SelectTransformationDropDown = uidropdown(app.ImageTransformationAppTab);
            app.SelectTransformationDropDown.Items = {'Histogram', 'Brightening', 'Negative', 'Log Transformation', 'Exponential Transformation', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Matching'};
            app.SelectTransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTransformationDropDownValueChanged, true);
            app.SelectTransformationDropDown.Value = 'Histogram';
            app.ScalercSpinnerLabel = uilabel(app.ImageTransformationAppTab);
            app.ScalercSpinnerLabel.Text = 'Scaler (c)';
            app.ScalercSpinner = uispinner(app.ImageTransformationAppTab);
            app.ScalercSpinner.ValueChangingFcn = createCallbackFcn(app, @ScalercSpinnerValueChanging, true);
            app.ScalercSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalercSpinnerValueChanged, true);
            app.ScalercSpinner.Value = 1;
            app.ExponentgammaSpinnerLabel = uilabel(app.ImageTransformationAppTab);
            app.ExponentgammaSpinnerLabel.Text = 'Exponent (gamma)';
            app.ExponentgammaSpinner = uispinner(app.ImageTransformationAppTab);
            app.ExponentgammaSpinner.ValueChangingFcn = createCallbackFcn(app, @ExponentgammaSpinnerValueChanging, true);
            app.ExponentgammaSpinner.ValueChangedFcn = createCallbackFcn(app, @ExponentgammaSpinnerValueChanged, true);
            app.ExponentgammaSpinner.Value = 1;
            app.SelectInputImageLabel_2 = uilabel(app.ImageTransformationAppTab);
            app.SelectInputImageLabel_2.Text = 'Select Target Image';
            app.SelectImageTargetButton = uibutton(app.ImageTransformationAppTab, 'push');
            app.SelectImageTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageTargetButtonPushed, true);
            app.SelectImageTargetButton.Text = 'Select Image';
            app.AdditionaSpinnerLabel = uilabel(app.ImageTransformationAppTab);
            app.AdditionaSpinnerLabel.Text = 'Addition (a)';
            app.AdditionaSpinner = uispinner(app.ImageTransformationAppTab);
            app.AdditionaSpinner.ValueChangingFcn = createCallbackFcn(app, @AdditionaSpinnerValueChanging, true);
            app.AdditionaSpinner.Limits = [-255 255];
            app.AdditionaSpinner.ValueChangedFcn = createCallbackFcn(app, @AdditionaSpinnerValueChanged, true);
            app.TransformationfunctionLabel = uilabel(app.ImageTransformationAppTab);
            app.TransformationfunctionLabel.Text = 'Transformation function:';
            app.FileInputLabel = uilabel(app.ImageTransformationAppTab);
            app.FileInputLabel.Text = 'File Input:';
            app.FiletargetLabel = uilabel(app.ImageTransformationAppTab);
            app.FiletargetLabel.Text = 'File target:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end